classdef StabilityMap < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        FileMenu                        matlab.ui.container.Menu
        LoadMenu                        matlab.ui.container.Menu
        SaveDataMenu                    matlab.ui.container.Menu
        SaveLogMenu                     matlab.ui.container.Menu
        RunMenu                         matlab.ui.container.Menu
        RunMenu_2                       matlab.ui.container.Menu
        ParametersforRateEquationsPanel  matlab.ui.container.Panel
        NameLabel                       matlab.ui.control.Label
        ValueLabel                      matlab.ui.control.Label
        X_label                         matlab.ui.control.Label
        Y_label                         matlab.ui.control.Label
        alphaEditFieldLabel             matlab.ui.control.Label
        alpha                           matlab.ui.control.EditField
        cavitylossrate1nsEditFieldLabel  matlab.ui.control.Label
        kappa                           matlab.ui.control.EditField
        carrierlossrate1nsEditFieldLabel  matlab.ui.control.Label
        gamma                           matlab.ui.control.EditField
        couplingABEditFieldLabel        matlab.ui.control.Label
        couplingAB                      matlab.ui.control.EditField
        couplingBAEditFieldLabel        matlab.ui.control.Label
        couplingBA                      matlab.ui.control.EditField
        pumpBEditFieldLabel             matlab.ui.control.Label
        pumpB                           matlab.ui.control.EditField
        pumpALabel                      matlab.ui.control.Label
        pumpA                           matlab.ui.control.EditField
        couplingphaseradEditFieldLabel  matlab.ui.control.Label
        phase                           matlab.ui.control.EditField
        detuningradnsEditFieldLabel     matlab.ui.control.Label
        detuning                        matlab.ui.control.EditField
        alphaX                          matlab.ui.control.CheckBox
        kappaX                          matlab.ui.control.CheckBox
        gammaX                          matlab.ui.control.CheckBox
        couplingABX                     matlab.ui.control.CheckBox
        couplingBAX                     matlab.ui.control.CheckBox
        phaseX                          matlab.ui.control.CheckBox
        detuningX                       matlab.ui.control.CheckBox
        pumpAX                          matlab.ui.control.CheckBox
        pumpBX                          matlab.ui.control.CheckBox
        alphaY                          matlab.ui.control.CheckBox
        kappaY                          matlab.ui.control.CheckBox
        gammaY                          matlab.ui.control.CheckBox
        couplingABY                     matlab.ui.control.CheckBox
        couplingBAY                     matlab.ui.control.CheckBox
        phaseY                          matlab.ui.control.CheckBox
        detuningY                       matlab.ui.control.CheckBox
        pumpAY                          matlab.ui.control.CheckBox
        pumpBY                          matlab.ui.control.CheckBox
        EqualGuides                     matlab.ui.control.CheckBox
        EqualPumps                      matlab.ui.control.CheckBox
        UIAxes                          matlab.ui.control.UIAxes
        ParameterRangePanel             matlab.ui.container.Panel
        xstart                          matlab.ui.control.EditField
        xend                            matlab.ui.control.EditField
        ystart                          matlab.ui.control.EditField
        yend                            matlab.ui.control.EditField
        xlabel                          matlab.ui.control.Label
        ylabel                          matlab.ui.control.Label
        startLabel                      matlab.ui.control.Label
        endLabel                        matlab.ui.control.Label
        GridpointsEditFieldLabel        matlab.ui.control.Label
        Gridpoints                      matlab.ui.control.EditField
        SinglePtn                       matlab.ui.control.CheckBox
        StabilityMapCoupledModeModelLabel  matlab.ui.control.Label
        Panel                           matlab.ui.container.Panel
        LoadButton                      matlab.ui.control.Button
        RunButton                       matlab.ui.control.Button
        SaveDataButton                  matlab.ui.control.Button
        SaveLogButton                   matlab.ui.control.Button
        Status                          matlab.ui.control.Label
        Log                             matlab.ui.control.TextArea
    end

    
    properties (Access = private)
        
        isSaved = true  % All changes have been saved
        isData = false  % There is no data loaded or calculated
        logSaved = false;
        NGrid = 31      % Default grid size
        
        aH      {mustBeNumeric}     % Linewidth enhancement factor
        kp      {mustBeNumeric}     % 1/(2*tau_p) cavity loss rate
        yn      {mustBeNumeric}     % 1/(tau_N) carrier recombination rate
        etaAB   {mustBeNumeric}     % Amplitude of coupling coefficient AB
        etaBA   {mustBeNumeric}     % Amplitude of coupling coefficient BA
        theta   {mustBeNumeric}     % Phase of coupling coefficient 
        DW      {mustBeNumeric}     % Detuning between the cavity resonances
        QA      {mustBeNumeric}     % Normalised pumping rate in guide A
        QB      {mustBeNumeric}     % Normalised pumping rate in guide B
        
        X       {mustBeNumeric}     % Grid for x-coordinate data
        Y       {mustBeNumeric}     % Grid for y-coordinate data
        Z       {mustBeNumeric}     % Grid for z-coordinate data
        
        x0      {mustBeNumeric}     % x coordinate start value
        xN      {mustBeNumeric}     % x coordinate end value
        y0      {mustBeNumeric}     % y coordinate start value
        yN      {mustBeNumeric}     % y coordinate end value
        
        xarray  {mustBeNumeric}     % Array for x data
        yarray  {mustBeNumeric}     % Array for x data
        
        pstruct                     % Structure for storing parameters during runs
        
        % Index references to parameters
        ALPHA = 1;
        KAPPA = 2;
        GAMMA = 3;
        ETA_AB = 4;
        ETA_BA = 5;
        ETA_EQUAL = 6;
        THETA = 7;
        DOMEGA = 8;
        PUMP_A = 9;
        PUMP_B = 10;
        PUMP_EQUAL = 11;
        UNKNOWN = 12;
        
        % Array of parameters preferable? To look at.
        
        xvar    {mustBeInteger}     % Index of parameter to vary on x
        yvar    {mustBeInteger}     % Index of parameter to vary on y
        
        labels = cell(10,1);        % Cell array of strings  
        
        % Session string
        session                     % Session name 

    end
    
    methods (Access = private)
        
        function falsifyX(app)
            
            % Ideally, I would like to replace this with a loop accessing
            % an array of pointers (if that is possible)
            
            if (app.alphaX.Value)
                app.alphaX.Value = false;
                app.alpha.Enable = true;
            end
            
            if (app.kappaX.Value)
                app.kappaX.Value = false;
                app.kappa.Enable = true;
            end
            
            if (app.gammaX.Value)
                app.gammaX.Value = false;
                app.gamma.Enable = true;
            end

            if (app.couplingABX.Value)
                app.couplingABX.Value = false;
                app.couplingAB.Enable = true;
            end
            
            if (app.couplingBAX.Value)
                app.couplingBAX.Value = false;
                app.couplingBA.Enable = true;
            end
            
            if (app.phaseX.Value)
                app.phaseX.Value = false;
                app.phase.Enable = true;
            end
            
            if (app.detuningX.Value)
                app.detuningX.Value = false;
                app.detuning.Enable = true;
            end
            
            if (app.pumpAX.Value)
                app.pumpAX.Value = false;
                app.pumpA.Enable = true;
            end
            
            if (app.pumpBX.Value)
                app.pumpBX.Value = false;
                app.pumpB.Enable = true;
            end
            
        end
        
        function falsifyY(app)
            
            % Ideally, I would like to replace this with a loop accessing
            % an array of pointers (if that is possible)
            
            if (app.alphaY.Value)
                app.alphaY.Value = false;
                app.alpha.Enable = true;
            end
            
            if (app.kappaY.Value)
                app.kappaY.Value = false;
                app.kappa.Enable = true;
            end
            
            if (app.gammaY.Value)
                app.gammaY.Value = false;
                app.gamma.Enable = true;
            end

            if (app.couplingABY.Value)
                app.couplingABY.Value = false;
                app.couplingAB.Enable = true;
            end
            
            if (app.couplingBAY.Value)
                app.couplingBAY.Value = false;
                app.couplingBA.Enable = true;
            end
            
            if (app.phaseY.Value)
                app.phaseY.Value = false;
                app.phase.Enable = true;
            end
            
            if (app.detuningY.Value)
                app.detuningY.Value = false;
                app.detuning.Enable = true;
            end
            
            if (app.pumpAY.Value)
                app.pumpAY.Value = false;
                app.pumpA.Enable = true;
            end
            
            if (app.pumpBY.Value)
                app.pumpBY.Value = false;
                app.pumpB.Enable = true;
            end
            
        end
        
        
        function loadData(app)
            
            [file,path] = uigetfile('*.mat');

            if not(isequal(file,0))
                
                try
                    
                    fullpath = fullfile(path,file);
                    data = load(fullpath);
                    
                    dataOK = true;
                    
                    % Check data has correct fields
                    if not(isfield(data, 'X'))
                        dataOK = false;
                        app.logMsg('Data does not have an X grid field');
                    end
                    
                    if not(isfield(data, 'Y'))
                        dataOK = false;
                        app.logMsg('Data does not have a Y grid field');
                    end
                    
                    if not(isfield(data, 'Z'))
                        dataOK = false;
                        app.logMsg('Data does not have an Z grid field');
                    end
                    
                    if not(isfield(data, 'xarray'))
                        dataOK = false;
                        app.logMsg('Data does not have an xarray field');
                    end
                    
                    if not(isfield(data, 'yarray'))
                        dataOK = false;
                        app.logMsg('Data does not have an yarray field');
                    end
                    
                    if not(isfield(data, 'aH'))
                        dataOK = false;
                        app.logMsg('Data does not have an aH (alpha) field');
                    end
                    
                    if not(isfield(data, 'kp'))
                        dataOK = false;
                        app.logMsg('Data does not have a kp (cavity loss rate) field');
                    end
                    
                    if not(isfield(data, 'yn'))
                        dataOK = false;
                        app.logMsg('Data does not have a yn (carrier loss rate) field');
                    end
                    
                    if not(isfield(data, 'etaAB'))
                        dataOK = false;
                        app.logMsg('Data does not have an etaAB (coupling AB) field');
                    end
                    
                    if not(isfield(data, 'etaBA'))
                        dataOK = false;
                        app.logMsg('Data does not have an etaAB (coupling BA) field');
                    end
                    
                    if not(isfield(data, 'theta'))
                        dataOK = false;
                        app.logMsg('Data does not have a theta (coupling phase) field');
                    end
                    
                    if not(isfield(data, 'DW'))
                        dataOK = false;
                        app.logMsg('Data does not have a DW (detuning) field');
                    end
                    
                    if not(isfield(data, 'QA'))
                        dataOK = false;
                        app.logMsg('Data does not have a QA (pump A) field');
                    end
                    
                    if not(isfield(data, 'QB'))
                        dataOK = false;
                        app.logMsg('Data does not have a QB (pump B) field');
                    end
                    
                    if not(isfield(data, 'xvar'))
                        dataOK = false;
                        app.logMsg('Data does not have an xvar field (which parameter is plotted on x-axis)');
                    end
                    
                    if not(isfield(data, 'yvar'))
                        dataOK = false;
                        app.logMsg('Data does not have a yvar field (which parameter is plotted on y-axis)');
                    end
                    
                    if (dataOK==true)
                        % So far at least...
                        % Check that grids have same dimensions
                        sz_x = size(data.X);
                        sz_y = size(data.Y);
                        sz_z = size(data.Z);
                        len_x = length(data.xarray);
                        len_y = length(data.xarray);
                        
                        Nx = sz_x(1);
                        if not(sz_x(2)==Nx)
                            dataOK = false;
                            app.logMsg('X grid is not square');
                        end
                        
                        Ny = sz_y(1);
                        if not(sz_y(2)==Ny)
                            dataOK = false;
                            app.logMsg('Y grid is not square');
                        end
                        
                        Nz = sz_z(1);
                        if not(sz_z(2)==Nz)
                            dataOK = false;
                            app.logMsg('Z grid is not square');
                        end
                        
                        if not(Nx==Ny)
                            dataOK = false;
                            app.logMsg('X and Y grids have different sizes');
                        end
                        
                        if not(Nx==Nz)
                            dataOK = false;
                            app.logMsg('X and Z grids have different sizes');
                        end
                        
                        if not(Ny==Nz)
                            dataOK = false;
                            app.logMsg('Y and Z grids have different sizes');
                        end
                        
                        if not(Nx==len_x)
                            dataOK = false;
                            app.logMsg('X grid has different side length to length of xarray');
                        end
                        
                        if not(Ny==len_y)
                            dataOK = false;
                            app.logMsg('Y grid has different side length to length of yarray');
                        end
                        
                    end
                    
                    if (dataOK==true)
                        
                        % If all OK, set data
                        app.NGrid = Nz;
                        app.Gridpoints.Value = num2str(app.NGrid);
                        
                        app.X = data.X;
                        app.Y = data.Y;
                        app.Z = data.Z;
                        app.xarray = data.xarray;
                        app.yarray = data.yarray;
                        app.xvar = data.xvar;
                        app.yvar = data.yvar;              
                        
                        % Set parameters
                        app.aH = data.aH;
                        app.alpha.Value = num2str(app.aH);
                        
                        app.kp = data.kp;
                        app.kappa.Value = num2str(app.kp);
                        
                        app.yn = data.yn;
                        app.gamma.Value = num2str(app.yn);
                        
                        app.etaAB = data.etaAB;
                        app.couplingAB.Value = num2str(app.etaAB);
                        
                        app.etaBA = data.etaBA;
                        app.couplingAB.Value = num2str(app.etaBA);
                        
                        app.theta = data.theta;
                        app.phase.Value = num2str(app.theta);
                        
                        app.DW = data.DW;
                        app.detuning.Value = num2str(app.DW);
                        
                        app.QA = data.QA;
                        app.pumpA.Value = num2str(app.QA);
                        
                        app.QB = data.QB;
                        app.pumpB.Value = num2str(app.QB);
                        
                        % Set limits
                        app.xlabel.Text = app.labels{app.xvar};
                        app.ylabel.Text = app.labels{app.yvar};
                        
                        app.x0 = app.xarray(1);
                        app.xN = app.xarray(app.NGrid);
                        app.xstart.Value = num2str(app.x0);
                        app.xend.Value = num2str(app.xN);
                        
                        app.y0 = app.yarray(1);
                        app.yN = app.yarray(app.NGrid);
                        app.ystart.Value = num2str(app.y0);
                        app.yend.Value = num2str(app.yN);
                        
                        % Set checks etc
                        switch app.xvar
                            case app.ALPHA              
                                app.alphaX.Value = true;
                                app.alphaXValueChanged();
            
                            case app.KAPPA          
                                app.kappaX.Value = true;
                                app.kappaXValueChanged();
            
                            case app.GAMMA           
                                app.gammaX.Value = true;
                                app.gammaXValueChanged();
                                
                            case app.ETA_AB                     
                                app.couplingABX.Value = true;
                                app.couplingABXValueChanged();
                                
                            case app.ETA_BA     
                                app.couplingBAX.Value = true;
                                app.couplingBAXValueChanged();
                                
                            case app.ETA_EQUAL
                                app.EqualGuides.Value = true;
                                app.EqualGuidesValueChanged();
                                app.couplingABX.Value = true;
                                app.couplingABXValueChanged();
                                
                            case app.THETA
                                app.phaseX.Value = true;
                                app.phaseXValueChanged();
                                
                            case app.DOMEGA 
                                app.detuningX.Value = true;
                                app.detuningXValueChanged();
                                
                            case app.PUMP_A
                                app.pumpAX.Value = true;
                                app.pumpAXValueChanged();
                                
                            case app.PUMP_B
                                app.pumpBX.Value = true;
                                app.pumpBXValueChanged();
                                
                            case app.PUMP_EQUAL
                                app.EqualPumps.Value = true;
                                app.EqualPumpsValueChanged();
                                app.pumpABX.Value = true;
                                app.pumpABXValueChanged();
                        end
                        
                        switch app.yvar
                            case app.ALPHA              
                                app.alphaY.Value = true;
                                app.alphaYValueChanged();
            
                            case app.KAPPA          
                                app.kappaY.Value = true;
                                app.kappaYValueChanged();
            
                            case app.GAMMA           
                                app.gammaY.Value = true;
                                app.gammaYValueChanged();
                                
                            case app.ETA_AB                     
                                app.couplingABY.Value = true;
                                app.couplingABYValueChanged();
                                
                            case app.ETA_BA     
                                app.couplingBAY.Value = true;
                                app.couplingBAYValueChanged();
                                
                            case app.ETA_EQUAL
                                app.EqualGuides.Value = true;
                                app.EqualGuidesValueChanged();
                                app.couplingABY.Value = true;
                                app.couplingABYValueChanged();
                                
                            case app.THETA
                                app.phaseY.Value = true;
                                app.phaseYValueChanged();
                                
                            case app.DOMEGA 
                                app.detuningY.Value = true;
                                app.detuningYValueChanged();
                                
                            case app.PUMP_A
                                app.pumpAY.Value = true;
                                app.pumpAYValueChanged();
                                
                            case app.PUMP_B
                                app.pumpBY.Value = true;
                                app.pumpBYValueChanged();
                                
                            case app.PUMP_EQUAL
                                app.EqualPumps.Value = true;
                                app.EqualPumpsValueChanged();
                                app.pumpAY.Value = true;
                                app.pumpAYValueChanged();
                        end   
                        
                        % Set single point to false
                        app.SinglePtn.Value = false;
                        app.SinglePtnValueChanged();
                        
                        % Plot the data
                        app.isData = true;
                        app.setStatus('Data loaded')
                        app.logMsg(fullpath)
                        app.plotData();
                        
                    else
                        
                        app.setStatus('Error loading data')
                        app.logMsg(fullpath)
                        
                        msgbox('Data does not have the correct structure - see log for details', 'Error loading data', 'error')

                    end
                    
                catch ME
                    
                    % Couldn't open file
                    msgbox(ME.message, 'Error loading data', 'error')
                    
                    app.setStatus('Error loading data');
                    app.logMsg(ME.message);
                    
                end
                
                % Hack to bring figure back to front
                app.UIFigure.Visible = 'off';
                app.UIFigure.Visible = 'on';

            end
  
        end
        
        function plotData(app)
            
            if not(app.isData)
                return
            end
            
            % Create colour map
            values = app.Z;
            ncols = 11;
            
            maxv = max(values(:));
            minv = min(values(:));
            
            vspan = maxv-minv;
            dv = vspan/(ncols - 1);
            
            sample = minv:dv:maxv;
            neg_mask = (sample <= 0);
            pos_mask = (sample > 0);
            
            c0 = 0.4;
            
            red = (1-c0)*abs(sample.*pos_mask/maxv) + c0*pos_mask;
            green = (1-c0)*abs(sample.*neg_mask/minv) + c0*neg_mask;
            blue = zeros(size(sample));
            
            mymap = transpose([red; green; blue]);
            
            contourf(app.UIAxes, app.X, app.Y, app.Z, 'EdgeColor', 'none')
            colormap(app.UIAxes, mymap)
            colorbar(app.UIAxes)
            app.UIAxes.XLabel.String = app.labels(app.xvar);
            app.UIAxes.YLabel.String = app.labels(app.yvar);
            
            app.UIAxes.XLim = [app.x0 app.xN];
            app.UIAxes.YLim = [app.y0 app.yN];
               
        end
        
        
        function setAxes(app)
            
            % Figure axes
            app.UIAxes.XLabel.String = app.labels(app.xvar);
            app.UIAxes.YLabel.String = app.labels(app.yvar);
            app.UIAxes.XLim = [app.x0 app.xN];
            app.UIAxes.YLim = [app.y0 app.yN];
            
            % Edit box labels
            app.xlabel.Text = app.labels(app.xvar);
            app.ylabel.Text = app.labels(app.yvar);
            
        end
        
        function solveSinglePtn(app)

            app.logMsg('Single point calculation:');
            app.logMsg('');
            app.printParameters();
            
            % Set default initial conditions
            N0 = zeros(5,1);
    
            % Approximate steady state solutions
            MA = 1.0;   % Carrier concentration in guide A
            MB = 1.0;   % Carrier concentration in guide A
    
            YA = sqrt(app.pstruct.QA/MA - 1);	% Optical amplitude in guide A
            YB = sqrt(app.pstruct.QB/MB - 1);	% Optical amplitude in guide A
    
            phi = pi;           % Phase between optical fields 
    
            N0(1) = MA;         % Carrier concentration in guide A
            N0(2) = MB;         % Carrier concentration in guide B
            N0(3) = YA;     	% Amplitude in guide A
            N0(4) = YB;     	% Amplitude in guide B
            N0(5) = phi;        % Relative phase between fields in A and B
            
            [~, found, E, esign] = StabilityMap.solve(N0, app.pstruct);
            
            if (found)
                
                Emax = max(real(E));
                
                if (esign < 0)
                    
                    app.setStatus('Stable solution found:');

                    
                else
                    
                    app.setStatus('Unstable solution found'); 
                    
                end
                
                app.logMsg(['Max real eig = ' num2str(Emax)]);

            else
                
                app.setStatus('No solution found'); 
                
            end
            
            app.logMsg('');

        end
        
        function printParameters(app)
            
            pstr = '    Parameter                 ';
            dash = '    ----------------------------------------';
            plen = length(pstr);
            pstr = [pstr 'Value'];
            
            app.logMsg(pstr);
            app.logMsg(dash);

            tab = '    ';
            
            % alpha
            pstr = [tab app.labels{app.ALPHA}];
            pstr = pad(pstr, plen);
            pstr = [pstr app.getValStr(app.ALPHA)];
            app.logMsg(pstr);
            
            % kappa
            pstr = [tab app.labels{app.KAPPA}];
            pstr = pad(pstr, plen);
            pstr = [pstr app.getValStr(app.KAPPA)];
            app.logMsg(pstr);
            
            % gamma
            pstr = [tab app.labels{app.GAMMA}];
            pstr = pad(pstr, plen);
            pstr = [pstr app.getValStr(app.GAMMA)];
            app.logMsg(pstr);
            
            % coupling AB 
            pstr = [tab app.labels{app.ETA_AB}];
            pstr = pad(pstr, plen);
            
            if (app.EqualGuides.Value)
                
                pstr = [pstr app.getValStr(app.ETA_EQUAL)];
                
            else
                
                pstr = [pstr app.getValStr(app.ETA_AB)];
                
            end
            
            app.logMsg(pstr);
            
            % coupling BA 
            pstr = [tab app.labels{app.ETA_BA}];
            pstr = pad(pstr, plen);
            
            if (app.EqualGuides.Value)
                
                pstr = [pstr app.getValStr(app.ETA_EQUAL)];
                
            else
                
                pstr = [pstr app.getValStr(app.ETA_BA)];
                
            end
            
            app.logMsg(pstr);
            
            % coupling phase 
            pstr = [tab app.labels{app.THETA}];
            pstr = pad(pstr, plen);
            pstr = [pstr app.getValStr(app.THETA)];
            app.logMsg(pstr);
            
            % detuning
            pstr = [tab app.labels{app.DOMEGA}];
            pstr = pad(pstr, plen);
            pstr = [pstr app.getValStr(app.DOMEGA)];
            app.logMsg(pstr);
            
            % pump (A)
            pstr = [tab app.labels{app.PUMP_A}];
            pstr = pad(pstr, plen);
            
            if (app.EqualGuides.Value)
                
                pstr = [pstr app.getValStr(app.PUMP_EQUAL)];
                
            else
                
                pstr = [pstr app.getValStr(app.PUMP_A)];
                
            end
            
            app.logMsg(pstr);
            
            % pump (B)
            pstr = [tab app.labels{app.PUMP_B}];
            pstr = pad(pstr, plen);
            
            if (app.EqualGuides.Value)
                
                pstr = [pstr app.getValStr(app.PUMP_EQUAL)];
                
            else
                
                pstr = [pstr app.getValStr(app.PUMP_B)];
                
            end
            
            app.logMsg(pstr);
            app.logMsg(dash);
            app.logMsg('');
            
        end
        
        function valstr = getValStr(app, index)
            
            if ((not(index==app.xvar) && not(index==app.yvar)) || app.SinglePtn.Value)
                
                switch index
                    case app.ALPHA              
                        valstr = num2str(app.pstruct.aH);
                        
                    case app.KAPPA          
                        valstr = num2str(app.pstruct.kp);

                    case app.GAMMA           
                        valstr = num2str(app.pstruct.yn);
                        
                    case app.ETA_AB                     
                        valstr = num2str(app.pstruct.etaAB);
                        
                    case app.ETA_BA     
                        valstr = num2str(app.pstruct.etaBA);
                        
                    case app.ETA_EQUAL     
                        valstr = num2str(app.pstruct.etaAB);
                        
                    case app.THETA
                        valstr = num2str(app.pstruct.theta);
                        
                    case app.DOMEGA 
                        valstr = num2str(app.pstruct.DW);
                        
                    case app.PUMP_A
                        valstr = num2str(app.pstruct.QA);
                        
                    case app.PUMP_B
                        valstr = num2str(app.pstruct.QB);
                        
                    case app.PUMP_EQUAL          
                        valstr = num2str(app.pstruct.QA);
                        
                    otherwise
                        valstr = '!';
                end
                
            else
                             
                valstr = 'varied';
                
            end
            
        end
        
        function generateMap(app)
            
            app.logMsg('Stability map calculation:');
            app.logMsg('');
            app.printParameters();
            
            astr = '    Axis   ';
            pstr = 'Parameter              ';
            plen = length(pstr);
            st_str = 'Start         ';
            ed_str = 'End           ';
            slen = length(st_str);
            dash = '    ----------------------------------------------------------';

            app.logMsg([astr pstr st_str ed_str]);
            app.logMsg(dash);
            
            xstr = '     X     ';
            
            switch app.xvar
                case app.ALPHA              
                    xstr = [xstr pad(app.labels{app.ALPHA},plen)];

                case app.KAPPA          
                    xstr = [xstr pad(app.labels{app.KAPPA},plen)];

                case app.GAMMA           
                    xstr = [xstr pad(app.labels{app.GAMMA},plen)];
                    
                case app.ETA_AB                     
                    xstr = [xstr pad(app.labels{app.ETA_AB},plen)];
                    
                case app.ETA_BA     
                    xstr = [xstr pad(app.labels{app.ETA_BA},plen)];
                    
                case app.ETA_EQUAL     
                    xstr = [xstr pad(app.labels{app.ETA_EQUAL},plen)];
                    
                case app.THETA
                    xstr = [xstr pad(app.labels{app.THETA},plen)];
                    
                case app.DOMEGA 
                    xstr = [xstr pad(app.labels{app.DOMEGA},plen)];
                    
                case app.PUMP_A
                    xstr = [xstr pad(app.labels{app.PUMP_A},plen)];
                    
                case app.PUMP_B
                    xstr = [xstr pad(app.labels{app.PUMP_B},plen)];
                    
                case app.PUMP_EQUAL
                    xstr = [xstr pad(app.labels{app.PUMP_EQUAL},plen)];
            end
                    
            st_str = pad(num2str(app.x0), slen);
            ed_str = pad(num2str(app.xN), slen);
            app.logMsg([xstr st_str ed_str]);
            
            ystr = '     Y     ';
            
            switch app.yvar
                case app.ALPHA              
                    ystr = [ystr pad(app.labels{app.ALPHA},plen)];

                case app.KAPPA          
                    ystr = [ystr pad(app.labels{app.KAPPA},plen)];

                case app.GAMMA           
                    ystr = [ystr pad(app.labels{app.GAMMA},plen)];
                    
                case app.ETA_AB                     
                    ystr = [ystr pad(app.labels{app.ETA_AB},plen)];
                    
                case app.ETA_BA     
                    ystr = [ystr pad(app.labels{app.ETA_BA},plen)];
                    
                case app.ETA_EQUAL     
                    ystr = [ystr pad(app.labels{app.ETA_EQUAL},plen)];
                    
                case app.THETA
                    ystr = [ystr pad(app.labels{app.THETA},plen)];
                    
                case app.DOMEGA 
                    ystr = [ystr pad(app.labels{app.DOMEGA},plen)];
                    
                case app.PUMP_A
                    ystr = [ystr pad(app.labels{app.PUMP_A},plen)];
                    
                case app.PUMP_B
                    ystr = [ystr pad(app.labels{app.PUMP_B},plen)];
                    
                case app.PUMP_EQUAL
                    ystr = [ystr pad(app.labels{app.PUMP_EQUAL},plen)];
            end
            
            st_str = pad(num2str(app.y0), slen);
            ed_str = pad(num2str(app.yN), slen);
            app.logMsg([ystr st_str ed_str]);
            app.logMsg('');
      
            % Set default initial conditions
            N0 = zeros(5,1);
    
            % Approximate steady state solutions
            MA = 1.0;   % Carrier concentration in guide A
            MB = 1.0;   % Carrier concentration in guide A
    
            YA = sqrt(app.pstruct.QA/MA - 1);	% Optical amplitude in guide A
            YB = sqrt(app.pstruct.QB/MB - 1);	% Optical amplitude in guide A
    
            phi = pi;           % Phase between optical fields 
    
            N0(1) = MA;         % Carrier concentration in guide A
            N0(2) = MB;         % Carrier concentration in guide B
            N0(3) = YA;     	% Amplitude in guide A
            N0(4) = YB;     	% Amplitude in guide B
            N0(5) = phi;        % Relative phase between fields in A and B
            
            % Set up x array 
            dx = (app.xN - app.x0)/(app.NGrid - 1);
            app.xarray = app.x0:dx:app.xN;
            
            % Set up y array 
            dy = (app.yN - app.y0)/(app.NGrid - 1);
            app.yarray = app.y0:dy:app.yN;
            
            %% Calculate map
            [app.X, app.Y] = meshgrid(app.xarray, app.yarray);
            
            z = 0;
            app.Z = z*app.X.*app.Y;
            
            h = waitbar(0, 'Generating stability map...',...
                'CreateCancelBtn','setappdata(gcbf,''canceling'',1)');
            
            setappdata(h,'canceling',0);
            cancelled = false;

            for m = 1:app.NGrid
                
                % Check if calculation has been cancelled
                if getappdata(h, 'canceling')
                    
                    cancelled = true;
                    
                    break
                    
                end
                
                % Set x varying parameter here
                switch app.xvar
                    case app.ALPHA              
                        app.pstruct.aH = app.xarray(m);
                        
                    case app.KAPPA          
                        app.pstruct.kp = app.xarray(m);

                    case app.GAMMA           
                        app.pstruct.yn = app.xarray(m);
                        
                    case app.ETA_AB                     
                        app.pstruct.etaAB = app.xarray(m);
                        
                    case app.ETA_BA     
                        app.pstruct.etaBA = app.xarray(m);
                        
                    case app.ETA_EQUAL     
                        app.pstruct.etaAB = app.xarray(m);
                        app.pstruct.etaBA = app.xarray(m);
                        
                    case app.THETA
                        app.pstruct.theta = app.xarray(m);
                        
                    case app.DOMEGA 
                        app.pstruct.DW = app.xarray(m);
                        
                    case app.PUMP_A
                        app.pstruct.QA = app.xarray(m);
                        
                    case app.PUMP_B
                        app.pstruct.QB = app.xarray(m);
                        
                    case app.PUMP_EQUAL
                        app.pstruct.QA = app.xarray(m);
                        app.pstruct.QB = app.xarray(m);
                end
                
                for n = 1:app.NGrid
                    
                    % Want the sweeps to go up and down to best capitalise
                    % on the previous calculation, so on even values of m,
                    % we reverse the index
                    
                    if (mod(m,2)==0)
                        ni = app.NGrid + 1 - n;
                    else
                        ni = n;
                    end
                
                    % Set y varying parameter here
                    switch app.yvar
                        case app.ALPHA              
                            app.pstruct.aH = app.yarray(ni);
                            
                        case app.KAPPA          
                            app.pstruct.kp = app.yarray(ni);
    
                        case app.GAMMA           
                            app.pstruct.yn = app.yarray(ni);
                            
                        case app.ETA_AB                     
                            app.pstruct.etaAB = app.yarray(ni);
                            
                        case app.ETA_BA     
                            app.pstruct.etaBA = app.yarray(ni);
                            
                        case app.ETA_EQUAL     
                            app.pstruct.etaAB = app.yarray(ni);
                            app.pstruct.etaBA = app.yarray(ni);
                            
                        case app.THETA
                            app.pstruct.theta = app.yarray(ni);
                            
                        case app.DOMEGA 
                            app.pstruct.DW = app.yarray(ni);
                            
                        case app.PUMP_A
                            app.pstruct.QA = app.yarray(ni);
                            
                        case app.PUMP_B
                            app.pstruct.QB = app.yarray(ni);
                            
                        case app.PUMP_EQUAL
                            app.pstruct.QA = app.yarray(ni);
                            app.pstruct.QB = app.yarray(ni);
                    end
                    
                    % Note that result of previous calculation is used as
                    % the starting point of the next
                    [N0, found, E, ~] = StabilityMap.solve(N0, app.pstruct);
                    
                    if (found)
                    
                        app.Z(ni,m) = max(real(E));   
                        
                    else
                        
                        app.Z(ni,m) = NaN;
                             
                    end 
                    
                end
                
                % Check if calculation has been cancelled
                if (cancelled==true)
                    
                    break
                    
                end
                
                waitbar(m/app.NGrid, h);
                
            end
            
            delete(h)
            
            if not(cancelled)
            
                app.isData = true;
                app.SaveDataButton.Enable = true;
                app.SaveDataMenu.Enable = true;
                
                app.plotData();
                
                app.setStatus('Map generated')
                
            else
                
                app.isData = false;
                app.SaveDataButton.Enable = false;
                app.SaveDataMenu.Enable = false;
                
                app.setStatus('Calculation aborted.') 
                
            end
  
        end
        
        function run(app)
            % Run calculation
            
            % Check parameter ranges set up OK
            if (or(app.xvar==app.UNKNOWN, app.yvar==app.UNKNOWN))
                
                msgbox('Unspecified parameter range!')
                
                return
                
            end
            
            % Store parameters for run
            app.pstruct.aH = app.aH;
            app.pstruct.kp = app.kp;
            app.pstruct.yn = app.yn;
            app.pstruct.etaAB = app.etaAB;
            app.pstruct.etaBA = app.etaBA;
            app.pstruct.theta = app.theta;
            app.pstruct.DW = app.DW;
            app.pstruct.QA = app.QA;
            app.pstruct.QB = app.QB;
            
            if (app.SinglePtn.Value)
                
                app.solveSinglePtn();
                
            else
                
                app.generateMap();
                
            end
            
        end
        
        function saveLog(app)
            % Save log to file
            
            filename = [app.session '.txt'];
            filename = strrep(filename,':','_');
            filename = strrep(filename,' ','_');
            [file,path] = uiputfile(filename);
            
            if not(isequal(file,0))
                
                try
                    
                    writecell(app.Log.Value, fullfile(path,file))
                    
                catch ME
                    
                    % Couldn't save file
                    msgbox(ME.message, 'Error saving file','error')
                    
                    app.logMsg(ME.message);
                    app.logMsg('');
                   
                end
                
                % Hack to bring figure back to front
                app.UIFigure.Visible = 'off';
                app.UIFigure.Visible = 'on';
                
            end
        end
        
        function saveData(app)
            % Save data to file
            
            filename = [app.session '.mat'];
            filename = strrep(filename,':','_');
            filename = strrep(filename,' ','_');
            [file,path] = uiputfile(filename);
            
            if not(isequal(file,0))
                
                try
                    
                    fullpath = fullfile(path,file);
                    
                    % Temporary structure for saving data
                    mystruct.X = app.X;
                    mystruct.Y = app.Y;
                    mystruct.Z = app.Z;
                    mystruct.xarray = app.xarray;
                    mystruct.yarray = app.yarray;
                    mystruct.aH = app.aH;
                    mystruct.kp = app.kp;
                    mystruct.yn = app.yn;
                    mystruct.etaAB = app.etaAB;
                    mystruct.etaBA = app.etaBA;
                    mystruct.theta = app.theta;
                    mystruct.DW = app.DW;
                    mystruct.QA = app.QA;
                    mystruct.QB = app.QB;
                    mystruct.xvar = app.xvar;
                    mystruct.yvar = app.yvar;
                                  
                    save(fullpath, '-struct', 'mystruct', 'X', 'Y', 'Z',...
                        'xarray', 'yarray', 'aH', 'kp',...
                        'yn', 'etaAB', 'etaBA', 'theta',...
                        'DW', 'QA', 'QB', 'xvar', 'yvar');
                    
                    app.setStatus('Data saved');
                    app.logMsg(fullpath)
                    app.logMsg('')
       
                catch ME
                    
                    % Couldn't save file
                    app.setStatus('Error saving data');
                    
                    msgbox(ME.message, 'Error saving data','error')
                    
                    app.logMsg(ME.message);
                    app.logMsg('');
                    
                end

                % Hack to bring figure back to front
                app.UIFigure.Visible = 'off';
                app.UIFigure.Visible = 'on';
                
            end
            
        end
        
        function setStatus(app, status)
            % status is a message string
            
            app.logMsg(status);
            app.logMsg('');
            
            app.Status.Text = ['Status: ' status];
            
        end
        
        function logMsg(app, msg)
            
            % write 'msg' to log
            line = length(app.Log.Value);
            app.Log.Value{line+1} = msg;
            
        end
        
        function setEqualGuides(app, value)
            
            if (value)
                
                % Enforce equal guides
                app.couplingBA.Value = app.couplingAB.Value;
                app.etaBA = app.etaAB;
                
                % Check for checked guides
                if (app.couplingABX.Value)
                    
                    app.xvar = app.ETA_EQUAL;
                    app.xlabel.Text = app.labels(app.xvar);
                    
                end
                
                if (app.couplingABY.Value)
                    
                    app.yvar = app.ETA_EQUAL;
                    app.ylabel.Text = app.labels(app.yvar);
                    
                end
                
                % Check for checked guides
                if (app.couplingBAX.Value)
                    
                    % Shift varied axis to coupled (AB)
                    app.couplingBAX.Value = false;
                    app.couplingABX.Value = true;
                    app.couplingAB.Enable = false;
                    app.xvar = app.ETA_EQUAL;
                    app.xlabel.Text = app.labels(app.xvar);
                    
                end
                
                if (app.couplingBAY.Value)
                    
                    app.couplingBAY.Value = false;
                    app.couplingABY.Value = true;
                    app.couplingAB.Enable = false;
                    app.yvar = app.ETA_EQUAL;
                    app.ylabel.Text = app.labels(app.yvar);
                    
                end
                
                app.couplingBA.Enable = false;
                app.couplingBAX.Enable = false;
                app.couplingBAY.Enable = false;
                      
            else
                
                % Allow unequal guides
                app.couplingBA.Enable = true;
                app.couplingBAX.Enable = true;
                app.couplingBAY.Enable = true;
                
                % Check if x axis set to 'equal eta'
                if (app.xvar == app.ETA_EQUAL)
                    
                    app.xvar = app.ETA_AB;
                    app.xlabel.Text = app.labels(app.xvar);
                    
                    % Check box and disable status should already be set
                    % for coupling (AB)
                    
                end
                
                % Check if y axis set to 'equal eta'
                if (app.yvar == app.ETA_EQUAL)
                    
                    app.yvar = app.ETA_AB;
                    app.ylabel.Text = app.labels(app.yvar);
                    
                    % Check box and disable status should already be set
                    % for coupling (AB)
                    
                end
                
            end
            
        end
        
        function setEqualPumps(app, value)
            
            if (value)
                
                % Enforce equal guides
                app.pumpB.Value = app.pumpA.Value;
                app.QB = app.QA;
                
                % Check for checked guides
                if (app.pumpAX.Value)
                    
                    app.xvar = app.PUMP_EQUAL;
                    app.xlabel.Text = app.labels(app.xvar);
                    
                end
                
                if (app.pumpAY.Value)
                    
                    app.yvar = app.PUMP_EQUAL;
                    app.ylabel.Text = app.labels(app.yvar);
                    
                end
                
                % Check for checked guides
                if (app.pumpBX.Value)
                    
                    % Shift varied axis to pump (A)
                    app.pumpBX.Value = false;
                    app.pumpAX.Value = true;
                    app.pumpA.Enable = false;
                    app.xvar = app.PUMP_EQUAL;
                    app.xlabel.Text = app.labels(app.xvar);
                    
                end
                
                if (app.pumpBY.Value)
                    
                    app.pumpBY.Value = false;
                    app.pumpAY.Value = true;
                    app.pumpA.Enable = false;
                    app.yvar = app.PUMP_EQUAL;
                    app.ylabel.Text = app.labels(app.yvar);
                    
                end
                
                app.pumpB.Enable = false;
                app.pumpBX.Enable = false;
                app.pumpBY.Enable = false;
                      
            else
                
                % Allow unequal guides
                app.pumpB.Enable = true;
                app.pumpBX.Enable = true;
                app.pumpBY.Enable = true;
                
                % Check if x axis set to equal eta
                if (app.xvar == app.PUMP_EQUAL)
                    
                    app.xvar = app.PUMP_A;
                    app.xlabel.Text = app.labels(app.xvar);
                    
                    % Check box and disable status should already be set
                    % for coupling (AB)
                    
                end
                
                % Check if y axis set to 'equal eta'
                if (app.yvar == app.PUMP_EQUAL)
                    
                    app.yvar = app.PUMP_A;
                    app.ylabel.Text = app.labels(app.yvar);
                    
                    % Check box and disable status should already be set
                    % for coupling (AB)
                    
                end    
            end         
        end
        
        function setSinglePtn(app, value)
            
            % Toggle parameter range controls
            app.Gridpoints.Enable = not(value);
            app.xend.Enable = not(value);
            app.yend.Enable = not(value);
            app.xstart.Enable = not(value);
            app.ystart.Enable = not(value);
            
            % Toggle parameter controls
            if (value)
                % Setting single point selection - enable all fields and
                % disable checkboxes
                app.alpha.Enable = true;
                app.alphaX.Enable = false;
                app.alphaY.Enable = false;
                
                app.kappa.Enable = true;
                app.kappaX.Enable = false;
                app.kappaY.Enable = false;
                
                app.gamma.Enable = true;
                app.gammaX.Enable = false;
                app.gammaY.Enable = false;
                
                app.couplingAB.Enable = true;
                app.couplingABX.Enable = false;
                app.couplingABY.Enable = false;
                
                if not(app.EqualGuides.Value)
                    app.couplingBA.Enable = true;
                end
                
                app.couplingBAX.Enable = false;
                app.couplingBAY.Enable = false;
                
                app.phase.Enable = true;
                app.phaseX.Enable = false;
                app.phaseY.Enable = false;
                
                app.detuning.Enable = true;
                app.detuningX.Enable = false;
                app.detuningY.Enable = false;
                
                app.pumpA.Enable = true;
                app.pumpAX.Enable = false;
                app.pumpAY.Enable = false;
                
                if not(app.EqualPumps.Value)
                    app.pumpB.Enable = true;
                end
                
                app.pumpBX.Enable = false;
                app.pumpBY.Enable = false;
                
            else
                
                % Enable all except selected fields - fields should already
                % be enabled
                app.alphaX.Enable = true;
                app.alphaY.Enable = true;
                app.kappaX.Enable = true;
                app.kappaY.Enable = true;
                app.gammaX.Enable = true;
                app.gammaY.Enable = true;
                app.couplingABX.Enable = true;
                app.couplingABY.Enable = true;
                
                if not(app.EqualGuides.Value)
                    app.couplingBAX.Enable = true;
                    app.couplingBAY.Enable = true;
                end
                
                app.phaseX.Enable = true;
                app.phaseY.Enable = true;
                app.detuningX.Enable = true;
                app.detuningY.Enable = true;
                app.pumpAX.Enable = true;
                app.pumpAY.Enable = true;
                
                if not(app.EqualPumps.Value)
                    app.pumpBX.Enable = true;
                    app.pumpBY.Enable = true;
                end
                
                switch app.xvar
                    case app.ALPHA              
                        app.alpha.Enable = false;
                        
                    case app.KAPPA          
                        app.kappa.Enable = false;

                    case app.GAMMA           
                        app.gamma.Enable = false;
                        
                    case app.ETA_AB                     
                        app.couplingAB.Enable = false;
                        
                    case app.ETA_BA     
                        app.couplingBA.Enable = false;
                        
                    case app.ETA_EQUAL     
                        app.couplingAB.Enable = false;
                        app.couplingBA.Enable = false;
                        
                    case app.THETA
                        app.phase.Enable = false;
                        
                    case app.DOMEGA 
                        app.detuning.Enable = false;
                        
                    case app.PUMP_A
                        app.pumpA.Enable = false;
                        
                    case app.PUMP_B
                        app.pumpB.Enable = false;
                        
                    case app.PUMP_EQUAL          
                        app.pumpA.Enable = false;
                        app.pumpB.Enable = false;
                        
                end
                
                switch app.yvar
                    case app.ALPHA              
                        app.alpha.Enable = false;
                        
                    case app.KAPPA          
                        app.kappa.Enable = false;

                    case app.GAMMA           
                        app.gamma.Enable = false;
                        
                    case app.ETA_AB                     
                        app.couplingAB.Enable = false;
                        
                    case app.ETA_BA     
                        app.couplingBA.Enable = false;
                        
                    case app.ETA_EQUAL     
                        app.couplingAB.Enable = false;
                        app.couplingBA.Enable = false;
                        
                    case app.THETA
                        app.phase.Enable = false;
                        
                    case app.DOMEGA 
                        app.detuning.Enable = false;
                        
                    case app.PUMP_A
                        app.pumpA.Enable = false;
                        
                    case app.PUMP_B
                        app.pumpB.Enable = false;
                        
                    case app.PUMP_EQUAL          
                        app.pumpA.Enable = false;
                        app.pumpB.Enable = false;
                        
                end
                
            end
            
        end
    end
        
    methods (Static)
        
        %   *** CORE FUNCTIONALITY ***
        
        function dN = rateEq(~, N0, param)
            % Rate equations for the coupled mode model. Dummy variable
            % stands in place of time for compatibility with Runge Kutta
            % solver (ode45) or equivalent
            %
            % Arguments:
            %
            %   N0      array of intial values
            %   param   structure containing parameters
            %
            % Returns:
            %
            %   dN      array containing rates of change
            
            MA = N0(1);         % Carrier concentration in guide A
            MB = N0(2);         % Carrier concentration in guide A
            YA = N0(3);         % Amplitude in guide A
            YB = N0(4);         % Amplitude in guide B
            phi = N0(5);        % Relative phase between fields in A and B
        
            % Intensities (all values real)
            IA = YA*YA;
            IB = YB*YB;
        
            yn = param.yn;          % 1/(tau_N) - carrier recombination rate
            kp = param.kp;          % 1/(2*tau_p) - cavity loss rate
            aH = param.aH;          % Linewidth enhancement factor
            QA = param.QA;          % Normalised pumping rate in guide A    
            QB = param.QB;          % Normalised pumping rate in guide A 
            etaAB = param.etaAB;    % Amplitude of coupling coefficient AB
            etaBA = param.etaBA;    % Amplitude of coupling coefficient BA
            theta = param.theta;    % Phase of coupling coefficient 
            DW = param.DW;          % Detuning between the cavity resonances
        
            dN = zeros(size(N0));
        
            % dMA/dt:
            dN(1) = yn*(QA - MA*(1.0 + IA));
        
            % dMB/dt:
            dN(2) = yn*(QB - MB*(1.0 + IB));
        
            % dYA/dt:
            dN(3) = kp*(MA - 1.0)*YA - etaAB*YB*sin(theta + phi);
        
            % dYB/dt:
            dN(4) = kp*(MB - 1.0)*YB - etaBA*YA*sin(theta - phi);
        
            % dphi/dt: 
            dN(5) = aH*kp*(MA - MB) - DW + etaAB*(YA/YB)*cos(theta - phi)...
                - etaBA*(YB/YA)*cos(theta + phi);
            
        end
        
        function dN = rateEqS(N0, param)
            % Autonomous (time independent) version of the rate equations
            % for use with fsolve to find the steady state solutions
            %
            % Arguments:
            %
            %   N0      array of intial values
            %   param   structure containing parameters
            %
            % Returns:
            %
            %   dN      array containing rates of change
            
            dN = StabilityMap.rateEq(0, N0, param);
            
        end
        
        function [Ns, found, E, esign] = solve(N0, param)
            % Finds steady state solutions of rate equations using a
            % nonlinear solver (fsolve)
            %
            % Arguments:
            %
            %   N0      array of intial values
            %   param   structure containing parameters
            %
            % Returns:
            %
            %   Ns      array containing the steady state solutions
            %
            %	found   Boolean indicating solutions found
            %
            %   E       Eigenvalues of Jacobian
            %
            %   esign   -1 indicates stable solution, 1 indicates unstable solution
            
            % Anonymous handle to function including passed parameters   
            fun = @(x) StabilityMap.rateEqS(x, param);    
            options = optimoptions('fsolve','Display','off');
            
            found = false;
        
            [Ns,~,xstat,~,J] = fsolve(fun, N0, options);
        
            if (xstat > 0)
        
                found = true;
        
                % Find eigenvalues of Jacobian
                [E] = eig(J);
        
                Etest = E;
                tol = 1E-6;
        
                % Check for zero eigenvalues
                for n = 1:length(E)
        
                    if ((E(n) == 0.0)||(abs(E(n)) < tol))
        
                        % Make these negative
                        Etest(n) = -1.0;
        
                    end
        
                end
        
                % Find maximum real component of the eigenvalues.
                % For stable solutions, this will be negative.
                Er = real(Etest);
                Emax = max(Er);
        
                if (Emax < 0.0)
        
                    esign = -1; % Stable solution
        
                else
        
                    esign = 1;  % Unstable solution
        
                end
        
            else
        
                % *** NO SOLUTION FOUND ***
                varnum = length(N0);
                E = zeros(varnum,1);
                esign = 0.0;
                Ns = N0;
        
            end
            
        end    
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function initialize(app)
            
            % Initialize array of labels
            app.labels{app.ALPHA} = 'alpha';
            app.labels{app.KAPPA} = 'cavity loss rate (1/ns)';
            app.labels{app.GAMMA} = 'carrier loss rate (1/ns)';
            app.labels{app.ETA_AB} = 'coupling (AB)';
            app.labels{app.ETA_BA} = 'coupling (BA)';
            app.labels{app.ETA_EQUAL} = 'coupling (equal)';
            app.labels{app.THETA} = 'coupling phase (rad)';
            app.labels{app.DOMEGA} = 'detuning (rad/ns)';
            app.labels{app.PUMP_A} = 'pump (A)';
            app.labels{app.PUMP_B} = 'pump (B)';
            app.labels{app.PUMP_EQUAL} = 'pump (equal)';
            app.labels{app.UNKNOWN} = '?';
 
            % Hard-coded default values
            app.aH = 2.0;           % Linewidth enhancement factor
            app.kp = 326.7974;      % Cavity loss rate 1/(2*tau_p) (1/ns)
            app.yn = 1.0;           % 1/tau_N, where tau_N is the carrier lifetime
            app.etaAB = 1.0;        % Amplitude of coupling coefficient AB
            app.etaBA = 1.0;        % Amplitude of coupling coefficient BA
            app.theta = 0.0;        % Phase of coupling coefficient
            app.DW  = 0.0;          % Detuning
            app.QA = 10;            % Normalised pump power in guide A
            app.QB = 10;            % Normalised pump power in guide B
            
            app.x0 = 0.15;          % x coordinate start value
            app.xN = 6.68;          % x coordinate end value
            app.y0 = 1.01;          % y coordinate start value
            app.yN = 32;            % y coordinate end value
            
            app.xvar = app.ETA_EQUAL;   % Variable to plot on x-axis
            app.yvar = app.PUMP_EQUAL;  % Variable to plot on y-axis
            
            % Initialize edit controls with default values
            app.alpha.Value = num2str(app.aH);           
            app.kappa.Value = num2str(app.kp);    
            app.gamma.Value = num2str(app.yn);         
            app.couplingAB.Value = num2str(app.etaAB);      
            app.couplingBA.Value = num2str(app.etaBA);      
            app.phase.Value = num2str(app.theta);      
            app.detuning.Value = num2str(app.DW);         
            app.pumpA.Value = num2str(app.QA);          
            app.pumpB.Value = num2str(app.QB);
            
            % Set initial map parameters
            app.xstart.Value = num2str(app.x0);
            app.xend.Value = num2str(app.xN);
            app.ystart.Value = num2str(app.y0);
            app.yend.Value = num2str(app.yN);

            % Set labels
            switch app.xvar
                
                case app.ALPHA
                    
                    app.xlabel.Text = app.labels(app.ALPHA);
                    app.alpha.Enable = false;
                    app.alphaX.Value = true;
                    
                case app.KAPPA
                    
                    app.xlabel.Text = app.labels(app.KAPPA);
                    app.kappa.Enable = false;
                    app.kappaX.Value = true;
                    
                case app.GAMMA
                    
                    app.xlabel.Text = app.labels(app.GAMMA);
                    app.gamma.Enable = false;
                    app.gammaX.Value = true;
                    
                case app.ETA_AB
                    
                    app.xlabel.Text = app.labels(app.ETA_AB);
                    app.couplingAB.Enable = false;
                    app.couplingABX.Value = true;
                    
                case app.ETA_BA
                    
                    app.xlabel.Text = app.labels(app.ETA_BA);
                    app.couplingBA.Enable = false;
                    app.couplingBAX.Value = true;
                    
                case app.ETA_EQUAL
                    
                    app.xlabel.Text = app.labels(app.ETA_EQUAL);
                    app.couplingAB.Enable = false;
                    app.couplingBA.Enable = false;
                    app.couplingABX.Value = true;
                    
                case app.THETA
                    
                    app.xlabel.Text = app.labels(app.THETA);
                    app.theta.Enable = false;
                    app.thetaX.Value = true;
                    
                case app.DOMEGA
                    
                    app.xlabel.Text = app.labels(app.DOMEGA);
                    app.detuning.Enable = false;
                    app.detuningX.Value = true;
                    
                case app.PUMP_A
                    
                    app.xlabel.Text = app.labels(app.PUMP_A);
                    app.pumpA.Enable = false;
                    app.pumpAX.Value = true;
                    
                case app.PUMP_B
                    
                    app.xlabel.Text = app.labels(app.PUMP_B);
                    app.pumpB.Enable = false;
                    app.pumpBX.Value = true;
                    
                case app.PUMP_EQUAL
                    
                    app.xlabel.Text = app.labels(app.PUMP_EQUAL);
                    app.pumpA.Enable = false;
                    app.pumpB.Enable = false;
                    app.pumpAX.Value = true;
                    
                otherwise
                    
                    app.xlabel.Text = app.labels(app.UNKNOWN);
            end
            
            switch app.yvar
                
                case app.ALPHA
                    
                    app.ylabel.Text = app.labels(app.ALPHA);
                    app.alpha.Enable = false;
                    app.alphaY.Value = true;
                    
                case app.KAPPA
                    
                    app.ylabel.Text = app.labels(app.KAPPA);
                    app.kappa.Enable = false;
                    app.kappaY.Value = true;
                    
                case app.GAMMA
                    
                    app.ylabel.Text = app.labels(app.GAMMA);
                    app.gamma.Enable = false;
                    app.gammaY.Value = true;
                    
                case app.ETA_AB
                    
                    app.ylabel.Text = app.labels(app.ETA_AB);
                    app.couplingAB.Enable = false;
                    app.couplingABY.Value = true;
                    
                case app.ETA_BA
                    
                    app.ylabel.Text = app.labels(app.ETA_BA);
                    app.couplingBA.Enable = false;
                    app.couplingBAY.Value = true;
                    
                case app.ETA_EQUAL
                    
                    app.ylabel.Text = app.labels(app.ETA_EQUAL);
                    app.couplingAB.Enable = false;
                    app.couplingBA.Enable = false;
                    app.couplingABY.Value = true;
                    
                case app.THETA
                    
                    app.ylabel.Text = app.labels(app.THETA);
                    app.theta.Enable = false;
                    app.thetaY.Value = true;
                    
                case app.DOMEGA
                    
                    app.ylabel.Text = app.labels(app.DOMEGA);
                    app.detuning.Enable = false;
                    app.detuningY.Value = true;
                    
                case app.PUMP_A
                    
                    app.ylabel.Text = app.labels(app.PUMP_A);
                    app.pumpA.Enable = false;
                    app.pumpAY.Value = true;
                    
                case app.PUMP_B
                    
                    app.ylabel.Text = app.labels(app.PUMP_B);
                    app.pumpB.Enable = false;
                    app.pumpBY.Value = true;
                    
                case app.PUMP_EQUAL
                    
                    app.ylabel.Text = app.labels(app.PUMP_EQUAL);
                    app.pumpA.Enable = false;
                    app.pumpB.Enable = false;
                    app.pumpAY.Value = true;
                    
                otherwise
                    
                    app.ylabel.Text = app.labels(app.UNKNOWN);
            end
               
            % Set equal guides checkbox
            if (app.etaAB == app.etaBA)
                
                app.EqualGuides.Value = true;
                app.couplingBA.Enable = false;
                app.couplingBAX.Enable = false;
                app.couplingBAY.Enable = false;
                
            end

            % Set equal pumps checkbox
            if (app.QA == app.QB)
                
                app.EqualPumps.Value = true;
                app.pumpB.Enable = false;
                app.pumpBX.Enable = false;
                app.pumpBY.Enable = false;
                
            end
            
            % Grid points
            app.Gridpoints.Value = num2str(app.NGrid);
            
            % Set figure axes
            app.setAxes();
            
            
            % Start log
            app.session = datestr(now);
            app.logMsg('STABILITY MAP VERSION 1.01');
            app.logMsg('(Scroll down to see most recent logs)');
            app.logMsg('');
            app.logMsg(['Session: ' app.session]);
            app.logMsg('');
            
        end

        % Value changed function: kappaX
        function kappaXValueChanged(app, event)
            
            value = app.kappaX.Value;
            
            if (value)
                % Change all other X values to false
                app.falsifyX();
                app.kappaX.Value = true;
                app.kappa.Enable = false;
                app.xvar = app.KAPPA;
                
                % Check corresponding Y checkbox
                if (app.kappaY.Value == true)
                    
                    app.kappaY.Value = false;
                    app.yvar = app.UNKNOWN;
                    
                end
                
            else
                
                app.kappa.Enable = true;
                app.xvar = app.UNKNOWN;
                
            end
            
            app.setAxes();
            
        end

        % Value changed function: gammaX
        function gammaXValueChanged(app, event)
            
            value = app.gammaX.Value;
            
            if (value)
                % Change all other X values to false
                app.falsifyX();
                app.gammaX.Value = true;
                app.gamma.Enable = false;
                app.xvar = app.GAMMA;
                
                
                % Check corresponding Y checkbox
                if (app.gammaY.Value == true)
                    
                    app.gammaY.Value = false;
                    app.yvar = app.UNKNOWN;
                    
                end
                
            else
                
                app.gamma.Enable = true;
                app.xvar = app.UNKNOWN;
                
            end
            
            app.setAxes();
            
        end

        % Value changed function: couplingABX
        function couplingABXValueChanged(app, event)
            
            value = app.couplingABX.Value;
            
            if (value)
                % Change all other X values to false
                app.falsifyX();
                app.couplingABX.Value = true;
                app.couplingAB.Enable = false;
                
                % Check corresponding Y checkbox
                if (app.couplingABY.Value == true)
                    
                    app.couplingABY.Value = false;
                    app.yvar = app.UNKNOWN;
                    
                end
                
                if (app.EqualGuides.Value)
                    
                    app.xvar = app.ETA_EQUAL;
                    
                else
                    
                    app.xvar = app.ETA_AB;
                    
                end   
                
            else
                
                app.couplingAB.Enable = true;
                app.xvar = app.UNKNOWN;
                
            end
            
            app.setAxes();
            
        end

        % Value changed function: couplingBAX
        function couplingBAXValueChanged(app, event)
            
            value = app.couplingBAX.Value;
            
            if (value)
                % Change all other X values to false
                app.falsifyX();
                app.couplingBAX.Value = true;
                app.couplingBA.Enable = false;
                
                app.xvar = app.ETA_BA;
                
                % Check corresponding Y checkbox
                if (app.couplingBAY.Value == true)
                    
                    app.couplingBAY.Value = false;
                    app.yvar = app.UNKNOWN;
                    
                end
                                
            else
                
                app.couplingBA.Enable = true;
                app.xvar = app.UNKNOWN;
                
            end
            
            app.setAxes();
            
        end

        % Value changed function: phaseX
        function phaseXValueChanged(app, event)
            
            value = app.phaseX.Value;
            
            if (value)
                % Change all other X values to false
                app.falsifyX();
                app.phaseX.Value = true;
                app.phase.Enable = false;
                app.xvar = app.THETA;
                
                % Check corresponding Y checkbox
                if (app.phaseY.Value == true)
                    
                    app.phaseY.Value = false;
                    app.yvar = app.UNKNOWN;
                    
                end
                
            else
                
                app.phase.Enable = true;
                app.xvar = app.UNKNOWN;
                
            end
            
            app.setAxes();
            
        end

        % Value changed function: detuningX
        function detuningXValueChanged(app, event)
            
            value = app.detuningX.Value;
            
            if (value)
                % Change all other X values to false
                app.falsifyX();
                app.detuningX.Value = true;
                app.detuning.Enable = false;
                app.xvar = app.DOMEGA;
                
                % Check corresponding Y checkbox
                if (app.detuningY.Value == true)
                    
                    app.detuningY.Value = false;
                    app.yvar = app.UNKNOWN;
                    
                end
                
            else
                
                app.detuning.Enable = true;
                app.xvar = app.UNKNOWN;
                
            end
            
            app.setAxes();
            
        end

        % Value changed function: pumpAX
        function pumpAXValueChanged(app, event)
            
            value = app.pumpAX.Value;
            
            if (value)
                % Change all other X values to false
                app.falsifyX();
                app.pumpAX.Value = true;
                app.pumpA.Enable = false;
                
                % Check corresponding Y checkbox
                if (app.pumpAY.Value == true)
                    
                    app.pumpAY.Value = false;
                    app.yvar = app.UNKNOWN;
                    
                end
                
                if (app.EqualPumps.Value)
                    
                    app.xvar = app.PUMP_EQUAL;
                    
                else
                    
                    app.xvar = app.PUMP_A;
                    
                end   
                
            else
                
                app.pumpA.Enable = true;
                app.xvar = app.UNKNOWN;
                
            end
            
            app.setAxes();
            
        end

        % Value changed function: pumpBX
        function pumpBXValueChanged(app, event)
            
            value = app.pumpBX.Value;
            
            if (value)
                % Change all other X values to false
                app.falsifyX();
                app.pumpBX.Value = true;
                app.pumpB.Enable = false;
                app.xvar = app.PUMP_B;
                
                % Check corresponding Y checkbox
                if (app.pumpBY.Value == true)
                    
                    app.pumpBY.Value = false;
                    app.yvar = app.UNKNOWN;
                    
                end
                
            else
                
                app.pumpB.Enable = true;
                app.xvar = app.UNKNOWN;
                
            end
            
            app.setAxes();
            
        end

        % Button pushed function: LoadButton
        function LoadButtonPushed(app, event)
            
            app.loadData();
            
        end

        % Value changed function: alphaY
        function alphaYValueChanged(app, event)
            
            value = app.alphaY.Value;
            
            if (value)
                % Change all other Y values to false
                app.falsifyY();
                app.alphaY.Value = true;
                app.alpha.Enable = false;
                app.yvar = app.ALPHA;
                
                % Check corresponding Y checkbox
                if (app.alphaX.Value == true)
                    
                    app.alphaX.Value = false;
                    app.xvar = app.UNKNOWN;
                    
                end
                
            else
                
                app.alpha.Enable = true;
                app.yvar = app.UNKNOWN;
                
            end
            
            app.setAxes();
            
        end

        % Value changed function: kappaY
        function kappaYValueChanged(app, event)
            
            value = app.kappaY.Value;
            
            if (value)
                % Change all other Y values to false
                app.falsifyY();
                app.kappaY.Value = true;
                app.kappa.Enable = false;
                app.yvar = app.KAPPA;
                
                % Check corresponding Y checkbox
                if (app.kappaX.Value == true)
                    
                    app.kappaX.Value = false;
                    app.xvar = app.UNKNOWN;
                    
                end
                
            else
                
                app.kappa.Enable = true;
                app.yvar = app.UNKNOWN;
                
            end
            
            app.setAxes();
            
        end

        % Value changed function: gammaY
        function gammaYValueChanged(app, event)
            
            value = app.gammaY.Value;
            
            if (value)
                % Change all other Y values to false
                app.falsifyY();
                app.gammaY.Value = true;
                app.gamma.Enable = false;
                app.yvar = app.GAMMA;
                
                % Check corresponding Y checkbox
                if (app.gammaX.Value == true)
                    
                    app.gammaX.Value = false;
                    app.xvar = app.UNKNOWN;
                    
                end
                
            else
                
                app.gamma.Enable = true;
                app.yvar = app.UNKNOWN;
                
            end
            
            app.setAxes();
            
        end

        % Value changed function: couplingABY
        function couplingABYValueChanged(app, event)
            
            value = app.couplingABY.Value;
            
            if (value)
                % Change all other Y values to false
                app.falsifyY();
                app.couplingABY.Value = true;
                app.couplingAB.Enable = false;
                
                if (app.EqualGuides.Value)
                    
                    app.couplingBA.Enable = false;
                    
                    app.yvar = app.ETA_EQUAL;
                    
                else
                    
                    app.yvar = app.ETA_AB;
                    
                end 
                
                % Check corresponding Y checkbox
                if (app.couplingABX.Value == true)
                    
                    app.couplingABX.Value = false;
                    app.xvar = app.UNKNOWN;
                    
                end
                
            else
                
                app.couplingAB.Enable = true;
                app.yvar = app.UNKNOWN;
                
            end
            
            app.setAxes();
            
        end

        % Value changed function: couplingBAY
        function couplingBAYValueChanged(app, event)
            
            value = app.couplingBAY.Value;
            
            if (value)
                % Change all other Y values to false
                app.falsifyY();
                app.couplingBAY.Value = true;
                app.couplingBA.Enable = false;
                app.yvar = app.ETA_BA;
                
                % Check corresponding Y checkbox
                if (app.couplingBAX.Value == true)
                    
                    app.couplingBAX.Value = false;
                    app.xvar = app.UNKNOWN;
                    
                end
                
            else
                
                app.couplingBA.Enable = true;
                app.yvar = app.UNKNOWN;
                
            end
            
            app.setAxes();
            
        end

        % Value changed function: phaseY
        function phaseYValueChanged(app, event)
            
            value = app.phaseY.Value;
            
            if (value)
                % Change all other Y values to false
                app.falsifyY();
                app.phaseY.Value = true;
                app.phase.Enable = false;
                app.yvar = app.THETA;
                
                % Check corresponding Y checkbox
                if (app.phaseX.Value == true)
                    
                    app.phaseX.Value = false;
                    app.xvar = app.UNKNOWN;
                    
                end
                
            else
                
                app.phase.Enable = true;
                app.yvar = app.UNKNOWN;
                
            end
            
            app.setAxes();
            
        end

        % Value changed function: detuningY
        function detuningYValueChanged(app, event)
            
            value = app.detuningY.Value;
            
            if (value)
                % Change all other Y values to false
                app.falsifyY();
                app.detuningY.Value = true;
                app.detuning.Enable = false;
                app.yvar = app.DOMEGA;
                
                % Check corresponding Y checkbox
                if (app.detuningX.Value == true)
                    
                    app.detuningX.Value = false;
                    app.xvar = app.UNKNOWN;
                    
                end
                
            else
                
                app.detuning.Enable = true;
                app.yvar = app.UNKNOWN;
                
            end
            
            app.setAxes();
            
        end

        % Value changed function: pumpAY
        function pumpAYValueChanged(app, event)
            
            value = app.pumpAY.Value;
            
            if (value)
                % Change all other Y values to false
                app.falsifyY();
                app.pumpAY.Value = true;
                app.pumpA.Enable = false;
                
                if (app.EqualPumps.Value)
                    
                    app.yvar = app.PUMP_EQUAL;
                    
                else
                    
                    app.yvar = app.PUMP_A;
                    
                end 
                
                % Check corresponding Y checkbox
                if (app.pumpAX.Value == true)
                    
                    app.pumpAX.Value = false;
                    app.xvar = app.UNKNOWN;
                    
                end

            else
                
                app.pumpA.Enable = true;
                app.yvar = app.UNKNOWN;
                
            end
            
            app.setAxes();
            
        end

        % Value changed function: pumpBY
        function pumpBYValueChanged(app, event)
            
            value = app.pumpBY.Value;
            
            if (value)
                % Change all other Y values to false
                app.falsifyY();
                app.pumpBY.Value = true;
                app.pumpB.Enable = false;
                app.yvar = app.PUMP_B;
                
                % Check corresponding Y checkbox
                if (app.pumpBX.Value == true)
                    
                    app.pumpBX.Value = false;
                    app.xvar = app.UNKNOWN;
                    
                end
                
                
            else
                
                app.pumpB.Enable = true; 
                app.yvar = app.UNKNOWN;
                
            end
            
            app.setAxes();
            
        end

        % Value changed function: alpha
        function alphaValueChanged(app, event)
            
            value = app.alpha.Value;
            
            if (isnan(str2double(value)))
                
                msgbox([value ' is not a number!'], 'Invalid value', 'error')
                
                % Set value back to original
                app.alpha.Value = num2str(app.aH);
                
            else
                
                % Set numerical property
                app.aH = str2double(value);        
                
            end
            
        end

        % Value changed function: kappa
        function kappaValueChanged(app, event)
            
            value = app.kappa.Value;
            
            if (isnan(str2double(value)))
                
                msgbox([value ' is not a number!'], 'Invalid value', 'error')
                
                % Set value back to original
                app.kappa.Value = num2str(app.kp);
                
            else
                
                % Set numerical property
                app.kp = str2double(value); 
                
            end
        end

        % Value changed function: gamma
        function gammaValueChanged(app, event)
            
            value = app.gamma.Value;
            
            if (isnan(str2double(value)))
                
                msgbox([value ' is not a number!'], 'Invalid value', 'error')
                
                % Set value back to original
                app.gamma.Value = num2str(app.yn);
                
            else
                
                % Set numerical property
                app.yn = str2double(value);
                
            end
        end

        % Value changed function: couplingAB
        function couplingABValueChanged(app, event)
            
            value = app.couplingAB.Value;
            
            if (isnan(str2double(value)))
                
                msgbox([value ' is not a number!'], 'Invalid value', 'error')

                % Set value back to original
                app.couplingAB.Value = num2str(app.etaAB);
                
            else
                
                % Set numerical property
                app.etaAB = str2double(value);
                
                if (app.EqualGuides.Value)
                    
                    app.couplingBA.Value = value;  
                    app.etaBA = str2double(value);
                    
                end
                
            end
            
        end

        % Value changed function: couplingBA
        function couplingBAValueChanged(app, event)
            
            value = app.couplingBA.Value;
            
            if (isnan(str2double(value)))
                
                msgbox([value ' is not a number!'], 'Invalid value', 'error')
                
                % Set value back to original
                app.couplingBA.Value = num2str(app.etaBA);
                
            else
                
                % Set numerical property
                app.etaBA = str2double(value);
                      
            end
            
        end

        % Value changed function: pumpB
        function pumpBValueChanged(app, event)
            
            value = app.pumpB.Value;
            
            if (isnan(str2double(value)))
                
                msgbox([value ' is not a number!'], 'Invalid value', 'error')
                
                % Set value back to original
                app.pumpB.Value = num2str(app.QB);
                
            else
                
                % Set numerical property
                app.QB = str2double(value);
                
            end
            
        end

        % Value changed function: pumpA
        function pumpAValueChanged(app, event)
            
            value = app.pumpA.Value;
            
            if (isnan(str2double(value)))
                
                msgbox([value ' is not a number!'], 'Invalid value', 'error')
                
                % Set value back to original
                app.pumpA.Value = num2str(app.QA);
                
            else
                
                % Set numerical property
                app.QA = str2double(value);
                
                if (app.EqualPumps.Value)
                    
                    app.pumpB.Value = value;
                    app.QB = str2double(value);
                    
                end
                
            end
            
        end

        % Value changed function: phase
        function phaseValueChanged(app, event)
            
            value = app.phase.Value;
            
            if (isnan(str2double(value)))
                
                msgbox([value ' is not a number!'], 'Invalid value', 'error')
                
                % Set value back to original
                app.phase.Value = num2str(app.theta);
                
            else
                
                % Set numerical property
                app.theta = str2double(value);
                
            end
            
        end

        % Value changed function: detuning
        function detuningValueChanged(app, event)
            
            value = app.detuning.Value;
            
            if (isnan(str2double(value)))
                
                msgbox([value ' is not a number!'], 'Invalid value', 'error')
                
                % Set value back to original
                app.detuning.Value = num2str(app.DW);
                
            else
                
                % Set numerical property
                app.DW = str2double(value);
                
            end
            
        end

        % Value changed function: alphaX
        function alphaXValueChanged(app, event)
            
            value = app.alphaX.Value;
            
            if (value)
                % Change all other Y values to false
                app.falsifyX();
                app.alphaX.Value = true;
                app.alpha.Enable = false;
                app.xvar = app.ALPHA;
                
                % Check corresponding Y checkbox
                if (app.alphaY.Value == true)
                    
                    app.alphaY.Value = false;
                    app.yvar = app.UNKNOWN;
                    
                end
                
            else
                
                app.alpha.Enable = true;
                app.xvar = app.UNKNOWN;
                
            end
            
            app.setAxes();
            
        end

        % Value changed function: xstart
        function xstartValueChanged(app, event)
            
            value = app.xstart.Value;
            
            if (isnan(str2double(value)))
                
                msgbox([value ' is not a number!'], 'Invalid value', 'error')
                
                % Set value back to original
                app.xstart.Value = num2str(app.x0);
                
            else
                
                % Set numerical property
                app.x0 = str2double(value);
                app.UIAxes.XLim = [app.x0 app.xN];
                app.UIAxes.YLim = [app.y0 app.yN];
                
            end
            
        end

        % Value changed function: xend
        function xendValueChanged(app, event)
            
            value = app.xend.Value;
            
            if (isnan(str2double(value)))
                
                msgbox([value ' is not a number!'], 'Invalid value', 'error')
                
                % Set value back to original
                app.xend.Value = num2str(app.xN);
                
            else
                
                % Set numerical property
                app.xN = str2double(value);
                app.UIAxes.XLim = [app.x0 app.xN];
                app.UIAxes.YLim = [app.y0 app.yN];
                
            end
            
        end

        % Value changed function: ystart
        function ystartValueChanged(app, event)
            
            value = app.ystart.Value;
            
            if (isnan(str2double(value)))
                
                msgbox([value ' is not a number!'], 'Invalid value', 'error')
                
                % Set value back to original
                app.ystart.Value = num2str(app.y0);
                
            else
                
                % Set numerical property
                app.y0 = str2double(value);
                app.UIAxes.XLim = [app.x0 app.xN];
                app.UIAxes.YLim = [app.y0 app.yN];
                
            end
            
        end

        % Value changed function: yend
        function yendValueChanged(app, event)
            
            value = app.yend.Value;
            
            if (isnan(str2double(value)))
                
                msgbox([value ' is not a number!'], 'Invalid value', 'error')
                
                % Set value back to original
                app.yend.Value = num2str(app.yN);
                
            else
                
                % Set numerical property
                app.yN = str2double(value);
                app.UIAxes.XLim = [app.x0 app.xN];
                app.UIAxes.YLim = [app.y0 app.yN];
                
            end
            
        end

        % Value changed function: Gridpoints
        function GridpointsValueChanged(app, event)
            
            value = app.Gridpoints.Value;
            
            if (isnan(str2double(value)))
                
                msgbox([value ' is not a number!'], 'Invalid value', 'error')
                
            else
                
                N = floor(str2double(value));
                
                if (N < 2)
                    
                    msgbox('Number of grid points must be an integer greater than 1')
                    
                else
                    
                    app.Gridpoints.Value = num2str(N);
                    
                end               
                
            end
            
        end

        % Value changed function: EqualGuides
        function EqualGuidesValueChanged(app, event)
            
            value = app.EqualGuides.Value;
            
            app.setEqualGuides(value);
            
        end

        % Value changed function: EqualPumps
        function EqualPumpsValueChanged(app, event)
            
            value = app.EqualPumps.Value;
            
            app.setEqualPumps(value);
            
        end

        % Value changed function: SinglePtn
        function SinglePtnValueChanged(app, event)
            
            value = app.SinglePtn.Value;
            
            app.setSinglePtn(value);
            
        end

        % Button pushed function: RunButton
        function RunButtonPushed(app, event)
            app.run();
        end

        % Button pushed function: SaveLogButton
        function SaveLogButtonPushed(app, event)
            app.saveLog();           
        end

        % Menu selected function: LoadMenu
        function LoadMenuSelected(app, event)
            app.loadData();
        end

        % Menu selected function: SaveLogMenu
        function SaveLogMenuSelected(app, event)
            app.saveLog();
        end

        % Button pushed function: SaveDataButton
        function SaveDataButtonPushed(app, event)
            app.saveData();
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.FileMenu = uimenu(app.UIFigure);
            app.FileMenu.Text = 'File';
            app.LoadMenu = uimenu(app.FileMenu);
            app.LoadMenu.MenuSelectedFcn = createCallbackFcn(app, @LoadMenuSelected, true);
            app.LoadMenu.Accelerator = 'l';
            app.LoadMenu.Text = 'Load';
            app.SaveDataMenu = uimenu(app.FileMenu);
            app.SaveDataMenu.Enable = 'off';
            app.SaveDataMenu.Accelerator = 'S';
            app.SaveDataMenu.Text = 'Save Data';
            app.SaveLogMenu = uimenu(app.FileMenu);
            app.SaveLogMenu.MenuSelectedFcn = createCallbackFcn(app, @SaveLogMenuSelected, true);
            app.SaveLogMenu.Text = 'Save Log';
            app.RunMenu = uimenu(app.UIFigure);
            app.RunMenu.Text = 'Run';
            app.RunMenu_2 = uimenu(app.RunMenu);
            app.RunMenu_2.Accelerator = 'r';
            app.RunMenu_2.Text = 'Run';
            app.ParametersforRateEquationsPanel = uipanel(app.UIFigure);
            app.ParametersforRateEquationsPanel.Title = 'Parameters for Rate Equations';
            app.NameLabel = uilabel(app.ParametersforRateEquationsPanel);
            app.NameLabel.Text = {'Name'; ''};
            app.ValueLabel = uilabel(app.ParametersforRateEquationsPanel);
            app.ValueLabel.Text = {'Value'; ''};
            app.X_label = uilabel(app.ParametersforRateEquationsPanel);
            app.X_label.Text = {'X'; ''};
            app.Y_label = uilabel(app.ParametersforRateEquationsPanel);
            app.Y_label.Text = {'Y'; ''};
            app.alphaEditFieldLabel = uilabel(app.ParametersforRateEquationsPanel);
            app.alphaEditFieldLabel.Text = 'alpha';
            app.alpha = uieditfield(app.ParametersforRateEquationsPanel, 'text');
            app.alpha.ValueChangedFcn = createCallbackFcn(app, @alphaValueChanged, true);
            app.alpha.Tooltip = {'Linewidth enhancement factor'};
            app.cavitylossrate1nsEditFieldLabel = uilabel(app.ParametersforRateEquationsPanel);
            app.cavitylossrate1nsEditFieldLabel.Text = 'cavity loss rate (1/ns)';
            app.kappa = uieditfield(app.ParametersforRateEquationsPanel, 'text');
            app.kappa.ValueChangedFcn = createCallbackFcn(app, @kappaValueChanged, true);
            app.kappa.Tooltip = {'1/(2*tp), where tp is the photon lifetime'};
            app.carrierlossrate1nsEditFieldLabel = uilabel(app.ParametersforRateEquationsPanel);
            app.carrierlossrate1nsEditFieldLabel.Text = 'carrier loss rate (1/ns)';
            app.gamma = uieditfield(app.ParametersforRateEquationsPanel, 'text');
            app.gamma.ValueChangedFcn = createCallbackFcn(app, @gammaValueChanged, true);
            app.gamma.Tooltip = {'1/tN, were tN is the carrier lifetime'};
            app.couplingABEditFieldLabel = uilabel(app.ParametersforRateEquationsPanel);
            app.couplingABEditFieldLabel.Text = 'coupling (AB)';
            app.couplingAB = uieditfield(app.ParametersforRateEquationsPanel, 'text');
            app.couplingAB.ValueChangedFcn = createCallbackFcn(app, @couplingABValueChanged, true);
            app.couplingAB.Tooltip = {'amplitude of coupling coefficient (AB)'};
            app.couplingBAEditFieldLabel = uilabel(app.ParametersforRateEquationsPanel);
            app.couplingBAEditFieldLabel.Text = 'coupling (BA)';
            app.couplingBA = uieditfield(app.ParametersforRateEquationsPanel, 'text');
            app.couplingBA.ValueChangedFcn = createCallbackFcn(app, @couplingBAValueChanged, true);
            app.couplingBA.Tooltip = {'amplitude of coupling coefficient (BA)'};
            app.pumpBEditFieldLabel = uilabel(app.ParametersforRateEquationsPanel);
            app.pumpBEditFieldLabel.Text = 'pump (B)';
            app.pumpB = uieditfield(app.ParametersforRateEquationsPanel, 'text');
            app.pumpB.ValueChangedFcn = createCallbackFcn(app, @pumpBValueChanged, true);
            app.pumpB.Tooltip = {'normalised pumping in guide B'};
            app.pumpALabel = uilabel(app.ParametersforRateEquationsPanel);
            app.pumpALabel.Text = 'pump (A)';
            app.pumpA = uieditfield(app.ParametersforRateEquationsPanel, 'text');
            app.pumpA.ValueChangedFcn = createCallbackFcn(app, @pumpAValueChanged, true);
            app.pumpA.Tooltip = {'normalised pumping in guide A'};
            app.couplingphaseradEditFieldLabel = uilabel(app.ParametersforRateEquationsPanel);
            app.couplingphaseradEditFieldLabel.Text = 'coupling phase (rad)';
            app.phase = uieditfield(app.ParametersforRateEquationsPanel, 'text');
            app.phase.ValueChangedFcn = createCallbackFcn(app, @phaseValueChanged, true);
            app.phase.Tooltip = {'phase of coupling coefficient'};
            app.detuningradnsEditFieldLabel = uilabel(app.ParametersforRateEquationsPanel);
            app.detuningradnsEditFieldLabel.Text = 'detuning (rad/ns)';
            app.detuning = uieditfield(app.ParametersforRateEquationsPanel, 'text');
            app.detuning.ValueChangedFcn = createCallbackFcn(app, @detuningValueChanged, true);
            app.detuning.Tooltip = {'laser detuning in rad/ns - N.B. NOT GHz'};
            app.alphaX = uicheckbox(app.ParametersforRateEquationsPanel);
            app.alphaX.ValueChangedFcn = createCallbackFcn(app, @alphaXValueChanged, true);
            app.alphaX.Tooltip = {'select this parameter to vary along x axis'};
            app.alphaX.Text = '';
            app.kappaX = uicheckbox(app.ParametersforRateEquationsPanel);
            app.kappaX.ValueChangedFcn = createCallbackFcn(app, @kappaXValueChanged, true);
            app.kappaX.Tooltip = {'select this parameter to vary along x axis'};
            app.kappaX.Text = '';
            app.gammaX = uicheckbox(app.ParametersforRateEquationsPanel);
            app.gammaX.ValueChangedFcn = createCallbackFcn(app, @gammaXValueChanged, true);
            app.gammaX.Tooltip = {'select this parameter to vary along x axis'};
            app.gammaX.Text = '';
            app.couplingABX = uicheckbox(app.ParametersforRateEquationsPanel);
            app.couplingABX.ValueChangedFcn = createCallbackFcn(app, @couplingABXValueChanged, true);
            app.couplingABX.Tooltip = {'select this parameter to vary along x axis'};
            app.couplingABX.Text = '';
            app.couplingBAX = uicheckbox(app.ParametersforRateEquationsPanel);
            app.couplingBAX.ValueChangedFcn = createCallbackFcn(app, @couplingBAXValueChanged, true);
            app.couplingBAX.Tooltip = {'select this parameter to vary along x axis'};
            app.couplingBAX.Text = '';
            app.phaseX = uicheckbox(app.ParametersforRateEquationsPanel);
            app.phaseX.ValueChangedFcn = createCallbackFcn(app, @phaseXValueChanged, true);
            app.phaseX.Tooltip = {'select this parameter to vary along x axis'};
            app.phaseX.Text = '';
            app.detuningX = uicheckbox(app.ParametersforRateEquationsPanel);
            app.detuningX.ValueChangedFcn = createCallbackFcn(app, @detuningXValueChanged, true);
            app.detuningX.Tooltip = {'select this parameter to vary along x axis'};
            app.detuningX.Text = '';
            app.pumpAX = uicheckbox(app.ParametersforRateEquationsPanel);
            app.pumpAX.ValueChangedFcn = createCallbackFcn(app, @pumpAXValueChanged, true);
            app.pumpAX.Tooltip = {'select this parameter to vary along x axis'};
            app.pumpAX.Text = '';
            app.pumpBX = uicheckbox(app.ParametersforRateEquationsPanel);
            app.pumpBX.ValueChangedFcn = createCallbackFcn(app, @pumpBXValueChanged, true);
            app.pumpBX.Tooltip = {'select this parameter to vary along x axis'};
            app.pumpBX.Text = '';
            app.alphaY = uicheckbox(app.ParametersforRateEquationsPanel);
            app.alphaY.ValueChangedFcn = createCallbackFcn(app, @alphaYValueChanged, true);
            app.alphaY.Tooltip = {'select this parameter to vary along y axis'};
            app.alphaY.Text = '';
            app.kappaY = uicheckbox(app.ParametersforRateEquationsPanel);
            app.kappaY.ValueChangedFcn = createCallbackFcn(app, @kappaYValueChanged, true);
            app.kappaY.Tooltip = {'select this parameter to vary along y axis'};
            app.kappaY.Text = '';
            app.gammaY = uicheckbox(app.ParametersforRateEquationsPanel);
            app.gammaY.ValueChangedFcn = createCallbackFcn(app, @gammaYValueChanged, true);
            app.gammaY.Tooltip = {'select this parameter to vary along y axis'};
            app.gammaY.Text = '';
            app.couplingABY = uicheckbox(app.ParametersforRateEquationsPanel);
            app.couplingABY.ValueChangedFcn = createCallbackFcn(app, @couplingABYValueChanged, true);
            app.couplingABY.Tooltip = {'select this parameter to vary along y axis'};
            app.couplingABY.Text = '';
            app.couplingBAY = uicheckbox(app.ParametersforRateEquationsPanel);
            app.couplingBAY.ValueChangedFcn = createCallbackFcn(app, @couplingBAYValueChanged, true);
            app.couplingBAY.Tooltip = {'select this parameter to vary along y axis'};
            app.couplingBAY.Text = '';
            app.phaseY = uicheckbox(app.ParametersforRateEquationsPanel);
            app.phaseY.ValueChangedFcn = createCallbackFcn(app, @phaseYValueChanged, true);
            app.phaseY.Tooltip = {'select this parameter to vary along y axis'};
            app.phaseY.Text = '';
            app.detuningY = uicheckbox(app.ParametersforRateEquationsPanel);
            app.detuningY.ValueChangedFcn = createCallbackFcn(app, @detuningYValueChanged, true);
            app.detuningY.Tooltip = {'select this parameter to vary along y axis'};
            app.detuningY.Text = '';
            app.pumpAY = uicheckbox(app.ParametersforRateEquationsPanel);
            app.pumpAY.ValueChangedFcn = createCallbackFcn(app, @pumpAYValueChanged, true);
            app.pumpAY.Tooltip = {'select this parameter to vary along y axis'};
            app.pumpAY.Text = '';
            app.pumpBY = uicheckbox(app.ParametersforRateEquationsPanel);
            app.pumpBY.ValueChangedFcn = createCallbackFcn(app, @pumpBYValueChanged, true);
            app.pumpBY.Tooltip = {'select this parameter to vary along y axis'};
            app.pumpBY.Text = '';
            app.EqualGuides = uicheckbox(app.ParametersforRateEquationsPanel);
            app.EqualGuides.ValueChangedFcn = createCallbackFcn(app, @EqualGuidesValueChanged, true);
            app.EqualGuides.Tooltip = {'When checked, coupling (AB) will be varied with coupling (BA)'};
            app.EqualGuides.Text = 'Equal guides';
            app.EqualPumps = uicheckbox(app.ParametersforRateEquationsPanel);
            app.EqualPumps.ValueChangedFcn = createCallbackFcn(app, @EqualPumpsValueChanged, true);
            app.EqualPumps.Tooltip = {'When checked, pump (A) will be varied with pump (B)'};
            app.EqualPumps.Text = 'Equal pumps';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, '')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.ParameterRangePanel = uipanel(app.UIFigure);
            app.ParameterRangePanel.Title = 'Parameter Range';
            app.xstart = uieditfield(app.ParameterRangePanel, 'text');
            app.xstart.ValueChangedFcn = createCallbackFcn(app, @xstartValueChanged, true);
            app.xend = uieditfield(app.ParameterRangePanel, 'text');
            app.xend.ValueChangedFcn = createCallbackFcn(app, @xendValueChanged, true);
            app.ystart = uieditfield(app.ParameterRangePanel, 'text');
            app.ystart.ValueChangedFcn = createCallbackFcn(app, @ystartValueChanged, true);
            app.yend = uieditfield(app.ParameterRangePanel, 'text');
            app.yend.ValueChangedFcn = createCallbackFcn(app, @yendValueChanged, true);
            app.xlabel = uilabel(app.ParameterRangePanel);
            app.xlabel.Text = 'x label';
            app.ylabel = uilabel(app.ParameterRangePanel);
            app.ylabel.Text = 'y label';
            app.startLabel = uilabel(app.ParameterRangePanel);
            app.startLabel.Text = 'start';
            app.endLabel = uilabel(app.ParameterRangePanel);
            app.endLabel.Text = 'end';
            app.GridpointsEditFieldLabel = uilabel(app.ParameterRangePanel);
            app.GridpointsEditFieldLabel.Text = 'Grid points';
            app.Gridpoints = uieditfield(app.ParameterRangePanel, 'text');
            app.Gridpoints.ValueChangedFcn = createCallbackFcn(app, @GridpointsValueChanged, true);
            app.Gridpoints.Tooltip = {'number of grid points to plot on each axis'};
            app.SinglePtn = uicheckbox(app.ParameterRangePanel);
            app.SinglePtn.ValueChangedFcn = createCallbackFcn(app, @SinglePtnValueChanged, true);
            app.SinglePtn.Text = 'Single point';
            app.StabilityMapCoupledModeModelLabel = uilabel(app.UIFigure);
            app.StabilityMapCoupledModeModelLabel.Text = 'Stability Map - Coupled Mode Model';
            app.Panel = uipanel(app.UIFigure);
            app.LoadButton = uibutton(app.Panel, 'push');
            app.LoadButton.ButtonPushedFcn = createCallbackFcn(app, @LoadButtonPushed, true);
            app.LoadButton.Tooltip = {'Load data from a file'};
            app.LoadButton.Text = 'Load';
            app.RunButton = uibutton(app.Panel, 'push');
            app.RunButton.ButtonPushedFcn = createCallbackFcn(app, @RunButtonPushed, true);
            app.RunButton.Tooltip = {'Generate the stability map'};
            app.RunButton.Text = 'Run';
            app.SaveDataButton = uibutton(app.Panel, 'push');
            app.SaveDataButton.ButtonPushedFcn = createCallbackFcn(app, @SaveDataButtonPushed, true);
            app.SaveDataButton.Enable = 'off';
            app.SaveDataButton.Tooltip = {'Save parameters and calculated data'};
            app.SaveDataButton.Text = 'Save Data';
            app.SaveLogButton = uibutton(app.Panel, 'push');
            app.SaveLogButton.ButtonPushedFcn = createCallbackFcn(app, @SaveLogButtonPushed, true);
            app.SaveLogButton.Tooltip = {'Save parameters and calculated data'};
            app.SaveLogButton.Text = 'Save Log';
            app.Status = uilabel(app.Panel);
            app.Status.Text = 'Status: ready';
            app.Log = uitextarea(app.UIFigure);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = StabilityMap

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @initialize)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end